% description: A matlab app for various signal processing algorithms.
% © BGU Acoustics lab
classdef ToolBox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image2                          matlab.ui.control.Image
        BGUAcousticsLabLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        SoundsListBox                   matlab.ui.control.ListBox
        SoundsListBoxLabel              matlab.ui.control.Label
        DeleteButton                    matlab.ui.control.Button
        RenameButton                    matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        SelectsourceTab                 matlab.ui.container.Tab
        filename                        matlab.ui.control.Label
        filePicker                      matlab.ui.control.Button
        SelectroomparametersTab         matlab.ui.container.Tab
        LoadRoomButton                  matlab.ui.control.Button
        SaveRoomButton                  matlab.ui.control.Button
        SourceListBox                   matlab.ui.control.ListBox
        SourceListBoxLabel              matlab.ui.control.Label
        CaluculateT60Button             matlab.ui.control.Button
        T60EditField                    matlab.ui.control.EditField
        thetaEditField                  matlab.ui.control.EditField
        thetaEditFieldLabel             matlab.ui.control.Label
        phiEditField                    matlab.ui.control.EditField
        phiLabel                        matlab.ui.control.Label
        REditField                      matlab.ui.control.EditField
        REditFieldLabel                 matlab.ui.control.Label
        ArraylocationPanel              matlab.ui.container.Panel
        XSlider                         matlab.ui.control.Slider
        XSliderLabel                    matlab.ui.control.Label
        YSlider                         matlab.ui.control.Slider
        YSliderLabel                    matlab.ui.control.Label
        ZSlider                         matlab.ui.control.Slider
        ZSliderLabel                    matlab.ui.control.Label
        SourcelocationPanel             matlab.ui.container.Panel
        ZSlider_2                       matlab.ui.control.Slider
        ZSlider_2Label                  matlab.ui.control.Label
        YSlider_2                       matlab.ui.control.Slider
        YSlider_2Label                  matlab.ui.control.Label
        XSlider_2                       matlab.ui.control.Slider
        XSlider_2Label                  matlab.ui.control.Label
        RoomDimensionsPanel             matlab.ui.container.Panel
        RoomReflactionSpinner           matlab.ui.control.Spinner
        RoomReflactionLabel             matlab.ui.control.Label
        roomY                           matlab.ui.control.Slider
        YSlider_3Label                  matlab.ui.control.Label
        roomZ                           matlab.ui.control.Slider
        ZSlider_3Label                  matlab.ui.control.Label
        roomX                           matlab.ui.control.Slider
        XLabel                          matlab.ui.control.Label
        SelectalgorithmTab              matlab.ui.container.Tab
        ProcessPanel                    matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        AlsTab                          matlab.ui.container.Tab
        EarlyreflactionsSlider          matlab.ui.control.Slider
        EarlyreflactionsSliderLabel     matlab.ui.control.Label
        beamformingTab                  matlab.ui.container.Tab
        N_array_disp                    matlab.ui.control.Label
        r_arraySlider                   matlab.ui.control.Slider
        r_arraySliderLabel              matlab.ui.control.Label
        N_arraySlider                   matlab.ui.control.Slider
        N_arraySliderLabel              matlab.ui.control.Label
        binauralTab                     matlab.ui.container.Tab
        AzimuthKnob                     matlab.ui.control.Knob
        AzimuthKnobLabel                matlab.ui.control.Label
        HeadrotationCheckBox            matlab.ui.control.CheckBox
        SHorderSpinner                  matlab.ui.control.Spinner
        SH_orderLabel                   matlab.ui.control.Label
        binauralN_arraySpinner          matlab.ui.control.Spinner
        N_arraySpinnerLabel             matlab.ui.control.Label
        hrtffilenameEditField           matlab.ui.control.EditField
        HRTFfileButton                  matlab.ui.control.Button
        binauralN_array_disp            matlab.ui.control.Label
        binauralr_arraySlider           matlab.ui.control.Slider
        r_arraySlider_2Label            matlab.ui.control.Label
        imagebeamformingTab             matlab.ui.container.Tab
        N_image                         matlab.ui.control.Slider
        N_arraySlider_2Label            matlab.ui.control.Label
        BSMTab                          matlab.ui.container.Tab
        BSM_T60Button                   matlab.ui.control.Button
        BSM_T60EditField                matlab.ui.control.EditField
        BSM_SHorderSpinner              matlab.ui.control.Spinner
        SHordersoundfieldSpinnerLabel   matlab.ui.control.Label
        BSM_MicsSpinner                 matlab.ui.control.Spinner
        MicsSpinnerLabel                matlab.ui.control.Label
        BSM_ArrayTypeDropDown           matlab.ui.control.DropDown
        ArrayTypeDropDownLabel          matlab.ui.control.Label
        BSM_azimuthKnob                 matlab.ui.control.Knob
        AzimuthLabel                    matlab.ui.control.Label
        BSM_RigidDropDown               matlab.ui.control.DropDown
        BSM_hrtffilenameEditField       matlab.ui.control.EditField
        BSM_HRTFfileButton              matlab.ui.control.Button
        BSM_ArrayRadiusLabel            matlab.ui.control.Label
        BSM_arrayRadiusSlider           matlab.ui.control.Slider
        BSM_HeadrotationCheckBox        matlab.ui.control.CheckBox
        Bilateral                       matlab.ui.container.Tab
        Bilateral_HRTFfileButton        matlab.ui.control.Button
        WidthKhzEditField               matlab.ui.control.NumericEditField
        WidthKhzEditFieldLabel          matlab.ui.control.Label
        CutoffKhzEditField              matlab.ui.control.NumericEditField
        CutoffKhzEditFieldLabel         matlab.ui.control.Label
        Band_Limited_TranslationCheckBox  matlab.ui.control.CheckBox
        headrotationdegEditField        matlab.ui.control.NumericEditField
        headrotationdegEditFieldLabel   matlab.ui.control.Label
        BilateralhrtffilenameEditField  matlab.ui.control.EditField
        head_radiusEditField            matlab.ui.control.NumericEditField
        head_radiusEditFieldLabel       matlab.ui.control.Label
        HeadRotationCheckBox            matlab.ui.control.CheckBox
        N_bilatSpinner                  matlab.ui.control.Spinner
        N_bilatSpinnerLabel             matlab.ui.control.Label
        selected                        matlab.ui.control.ListBox
        selectedLabel                   matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        playandproccessTab              matlab.ui.container.Tab
        LoadingSpinner                  matlab.ui.control.HTML
        buttonsPanel                    matlab.ui.container.Panel
        saveAll                         matlab.ui.control.Button
        bypass                          matlab.ui.control.CheckBox
        filesave                        matlab.ui.control.Button
        processBtn                      matlab.ui.control.Button
        play                            matlab.ui.control.Button
        AudiodeviceDropDown             matlab.ui.control.DropDown
        AudiodeviceDropDownLabel        matlab.ui.control.Label
        DSwitch                         matlab.ui.control.ToggleSwitch
        SwitchLabel                     matlab.ui.control.Label
        SoundViewButtonGroup            matlab.ui.container.ButtonGroup
        FrequencyButton                 matlab.ui.control.RadioButton
        TimeButton                      matlab.ui.control.RadioButton
        plots                           matlab.ui.control.CheckBox
        soundView                       matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % for more information visit https://github.com/ACLab-BGU/general/tree/main/ToolboxApp
    properties  (Constant)
        processors = containers.Map;
        tabs = containers.Map;
        sounds = containers.Map;
        origin =containers.Map;
    end
    properties (Access = private)
        x % Description
        fs % Description
        
        path = "ToolBoxApp/data/sounds/";
        file = 'female_speech.wav';

        Source = [8.25 3.8 1.7];
        
        room = Room([15.5 9.8 7.5],[5 5 1.7],0.9)
        choosen_sound = 'origin'
        soundViewopt='time';
        device = -1;
        player
        azimuth=1;
        counter = 0;
        
    end
    
    methods (Access = private)
        
        function proccess(app,to_run)
            % run the choosen algorithm with the signals
            count = 1;
            for k=keys(app.origin)
                sig{count} = app.origin(k{1}).signal;
                source{count} = app.origin(k{1}).position;
                freq{count} = app.origin(k{1}).fs;
                count = count +1;
            end
            [app.x,app.fs] = app.processors(to_run).process(sig,freq,app.room.RoomSize,source,app.room.ArrayPosition,app.room.Reflections);
            app.updateSoundView()
        end
        
        function updateRoom(app)
            % Calculate room visualization.
            app.roomX.Value = app.room.RoomSize(1);
            app.roomY.Value = app.room.RoomSize(2);
            app.roomZ.Value = app.room.RoomSize(3);
            %% keep things inside the room
            app.room.ArrayPosition(1) = max(0,min(app.roomX.Value, app.room.ArrayPosition(1)));
            app.room.ArrayPosition(2) = max(0,min(app.roomY.Value, app.room.ArrayPosition(2)));

            app.room.ArrayPosition(3) = max(0,min(app.roomZ.Value, app.room.ArrayPosition(3)));
            
                
            [X,Y,Z] = sphere(7);
            %% plot room
            cla(app.UIAxes);
            PLOTCUBE(app.UIAxes,app.room.RoomSize,[ 0 0 0],.2,[0 0 0])
            hold( app.UIAxes, 'on' )
            for k=keys(app.origin)
                 if strcmp(k{1},app.SourceListBox.Value)
                    app.sphere_plot(app.origin(k{1}).position,app.origin(k{1}).color,[1 1 1],k{1})
                 else
                     app.sphere_plot(app.origin(k{1}).position,app.origin(k{1}).color,[0 0 0],k{1})
                 end
            end
           
            surf(app.UIAxes,(X/2+app.room.ArrayPosition(1)),(Y/2+app.room.ArrayPosition(2)),(Z/2+app.room.ArrayPosition(3)),'FaceColor','r','FaceAlpha',0.6)
            text(app.UIAxes,(app.room.ArrayPosition(1)),(app.room.ArrayPosition(2)),(app.room.ArrayPosition(3)+2),"array",'Color','white','FontWeight','bold','FontSize',14)
            
            value = app.DSwitch.Value;
            if strcmp(value,'2D')
                 view(app.UIAxes,2)
            else
                view(app.UIAxes,3)

            end
            
            % array direction
            mArrow3([app.room.ArrayPosition(1) app.room.ArrayPosition(2) app.room.ArrayPosition(3)],([app.room.ArrayPosition(1) app.room.ArrayPosition(2) app.room.ArrayPosition(3)]+[cosd(app.azimuth) sind(app.azimuth) 0]*2),'axes',app.UIAxes,'tipWidth',0.2,'stemWidth',0.01);
            
            % verify the spherical coordinates
            pos = app.origin(app.SourceListBox.Value).position;
            [phi,theta,r] = app.cart_to_sphere(pos(1)-app.room.ArrayPosition(1),pos(2)-app.room.ArrayPosition(2),pos(3)-app.room.ArrayPosition(3));
            
            % sync R,Phi,Theta coordinate with X,Y,Z
            app.RoomReflactionSpinner.Value = app.room.Reflections;
            app.REditField.Value = sprintf('%.2f',r);
            app.phiEditField.Value = sprintf('%.2f',phi);
            app.thetaEditField.Value = sprintf('%.2f',theta);
            
            app.XSlider_2.Value = pos(1);
            app.YSlider_2.Value = pos(2);
            app.ZSlider_2.Value = pos(3);
            app.XSlider.Value = app.room.ArrayPosition(1);
            app.YSlider.Value = app.room.ArrayPosition(2);
            app.ZSlider.Value = app.room.ArrayPosition(3);

        end
        
        function updateSoundView(app)
            % calculate and plot the choosen signal view
            s = app.SoundsListBox.Value;
            sig = app.sounds(s).signal;
            f = app.sounds(s).fs;
            if strcmp('time',app.soundViewopt)
               
                time=(1:length(sig))/f;
                plot(app.soundView,time,sig)
                app.soundView.XLabel.String = "time";
                app.soundView.YLabel.String = "Amplitude";
                app.soundView.XScale = 'linear';
                app.soundView.XLim = [0,max(time)+1];
            else
                Length_audio=length(sig);
                df=f/Length_audio;
                frequency_audio=-f/2:df:f/2-df;
                app.soundView.XLabel.String = "frequency";
                app.soundView.YLabel.String = "Amplitude";
                
                FFT_audio_in=fftshift(fft(sig))/length(fft(sig));
                semilogx(app.soundView ,frequency_audio,abs(FFT_audio_in));
                app.soundView.XLim = [0,20000];
            end
        end


        function result = update_azimuth(app)
            % creating a call back for updating the azimuth view after
            % knob's value changed.
            % we need this only because matlab dont have a reversed knob.
            function up(azimuth)
                app.azimuth = 360-azimuth;
                app.updateRoom()
            end
            result = @up;
        end
        
        function result = azimuth_disable(app)
            % call back for reset azimuth value
            function up(azimuth)
                if azimuth
                    app.AzimuthKnob.Enable = true;
                    
                    app.updateRoom()
                else
                    app.AzimuthKnob.Enable = false;
                    app.AzimuthKnob.Value = 0;
                    app.azimuth = 360;
                    
                    app.updateRoom()
                end
            end
            result = @up;
        end
        function result = BSM_azimuth_disable(app)
            function up(azimuth)
                if azimuth
                    app.BSM_azimuthKnob.Enable = true;
                    
                    app.updateRoom()
                else
                    app.BSM_azimuthKnob.Enable = false;
                    app.BSM_azimuthKnob.Value = 0;
                    app.azimuth = 360;
                    
                    app.updateRoom()
                end
            end
            result = @up;
        end
        
        function [phi,theta,r] = cart_to_sphere(~,x,y,z)
              % cartesian coordinates to R,Phi, Theta
            
              r = sqrt(x^2+y^2+z^2);
              theta = rad2deg(acos(z/r));
              phi = rad2deg(atan2(y,x));
              if isnan(phi)
                  if y==0
                    phi =0;
                  elseif y>0
                        phi = rad2deg(pi/2);
                  else
                      phi = -rad2deg(pi/2);
                  end
                  
              end
              if phi<0
                  phi=phi+360;
              end
              if isnan(theta)
                  theta = rad2deg(acos(0));
              end
        end
        
        function [x,y,z] = sphere_to_cart(~,r,phi,theta)
                %  R,Phi, Theta to cartesian coordinates

              x = r*cos(deg2rad(phi))*sin(deg2rad(theta));
              y = r*sin(deg2rad(phi))*sin(deg2rad(theta));
              z = r*cos(deg2rad(theta));
            
        end
        
        function sphere_plot(app,Source,color,EdgeColor,name)
            % plot sphere
            Source(1) = max(0,min(app.roomX.Value, Source(1)));
            Source(2) = max(0,min(app.roomY.Value, Source(2)));

            Source(3) = max(0,min(app.roomZ.Value, Source(3)));
            [X,Y,Z] = sphere(7);
            surf(app.UIAxes,(X/2+Source(1)),(Y/2+Source(2)),(Z/2+Source(3)),'FaceColor',color,'EdgeColor',EdgeColor,'FaceAlpha',0.7)
            text(app.UIAxes,(Source(1)),(Source(2)),(Source(3))+2,erase(name,'.wav'),'Color',EdgeColor,'Interpreter', 'none','FontWeight','bold','FontSize',12)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % defining room parameters. 
            if contains(pwd,'ToolboxApp')
                addpath(genpath('../'));
                app.path = 'data/sounds/';
            end
           
            startup_script();
            addpath(genpath('ToolboxApp'));
            set(groot,'defaultFigureVisible','off');
            
            
            %set value to the defualts.
            app.roomX.Value = app.room.RoomSize(1);
            app.roomY.Value = app.room.RoomSize(2);
            app.roomZ.Value = app.room.RoomSize(3);
            app.XSlider_2.Value = app.Source(1);
            app.YSlider_2.Value = app.Source(2);
            app.ZSlider_2.Value = app.Source(3);
            app.XSlider.Value = app.room.ArrayPosition(1);
            app.YSlider.Value = app.room.ArrayPosition(2);
            app.ZSlider.Value = app.room.ArrayPosition(3);
            [phi,theta,r] = app.cart_to_sphere(app.Source(1)-app.room.ArrayPosition(1),app.Source(2)-app.room.ArrayPosition(2),app.Source(3)-app.room.ArrayPosition(3));
            
            
            app.RoomReflactionSpinner.Value = app.room.Reflections;
            app.REditField.Value = sprintf('%.2f',r);
            app.phiEditField.Value = sprintf('%.2f',phi);

            app.thetaEditField.Value = sprintf('%.2f',theta);

            % set sliders limits.
            app.XSlider_2.Limits = [0,app.roomX.Value];
            app.YSlider_2.Limits = [0,app.roomY.Value];
            app.ZSlider_2.Limits = [0,app.roomZ.Value];
            
            app.XSlider.Limits = [0,app.roomX.Value];
            app.YSlider.Limits = [0,app.roomY.Value];
            app.ZSlider.Limits = [0,app.roomZ.Value];
            

            app.AlsTab.Parent =[];
            app.beamformingTab.Parent =[];
            app.imagebeamformingTab.Parent =[];
            
            
            % create Map structure for all the tabs
%             app.tabs('Als') = app.AlsTab;
%             app.tabs('beam') = app.beamformingTab;
%             app.tabs('image beamforming') = app.imagebeamformingTab;
            app.tabs('binaural') = app.binauralTab;
            app.tabs('BSM') = app.BSMTab;
            app.tabs('Bilateral') = app.Bilateral;
            
            % hiding unselected tabs
            for k = keys(app.tabs)
                tab =  app.tabs(k{1});
                tab.Parent =[];
                app.tabs(k{1}) = tab;
            end
            
            app.ListBox.Items = keys(app.tabs);
            % building our processors and binding them to the corresponding
            % GUI component. use the 'r' flag for rounding.
            %
            %Image method beamforming
%             app.processors('image beamforming') = imageMethodBeamformingProccessor();
%             app.processors('image beamforming').bind(app.N_image,'N','r');

            %Bilateral Ambisonics reproduction        
            app.processors('Bilateral') = BilateralProccesor();
            app.processors('Bilateral').bind(app.N_bilatSpinner,'N_bilat');
            app.processors('Bilateral').bind(app.head_radiusEditField,'head_radius'); %'r' for rounding values
            app.processors('Bilateral').bind(app.BilateralhrtffilenameEditField,'HRTFpath');
            app.processors('Bilateral').bind(app.HeadRotationCheckBox,'headRotation',app.azimuth_disable);
            app.processors('Bilateral').bind(app.headrotationdegEditField,'rot_ang','r',app.update_azimuth);
            app.processors('Bilateral').bind(app.Band_Limited_TranslationCheckBox,'LPF');
            app.processors('Bilateral').bind(app.CutoffKhzEditField,'Fc');
            app.processors('Bilateral').bind(app.WidthKhzEditField,'Width');


            % binaural reproduction
            app.processors('binaural') = binauralProccesor();
            app.processors('binaural').bind(app.binauralN_arraySpinner,'N_array','r');
            app.processors('binaural').bind(app.binauralr_arraySlider,'r_array');
            app.processors('binaural').bind(app.hrtffilenameEditField,'HRTFpath');
            app.processors('binaural').bind(app.SHorderSpinner,'ShOrder','r');
            app.processors('binaural').bind(app.AzimuthKnob,'rot_idx','r',app.update_azimuth);
            app.processors('binaural').bind(app.HeadrotationCheckBox,'headRotation','r',app.azimuth_disable);
            app.AzimuthKnob.Enable = false;
            
%             app.AzimuthKnob.MajorTickLabels = flip(app.AzimuthKnob.MajorTickLabels);
            % beamforming
%             app.processors('beam') = beamformingProccessor();
%             app.processors('beam').bind(app.N_arraySlider,'N_array','r');
%             app.processors('beam').bind(app.r_arraySlider,'r_array');
            
            %als
%             app.processors('Als') = alsProcessor();
%             app.processors('Als').bind(app.EarlyreflactionsSlider,'early reflections','r');

            % BSM processor                        
            app.processors('BSM') = BSMProccesor();            
            app.processors('BSM').bind(app.BSM_ArrayTypeDropDown,'arrayType');
            app.processors('BSM').bind(app.BSM_RigidDropDown,'rigidArray');
            app.processors('BSM').bind(app.BSM_arrayRadiusSlider,'r_array');
            app.processors('BSM').bind(app.BSM_MicsSpinner,'M');
            app.processors('BSM').bind(app.BSM_SHorderSpinner,'N_PW','r');
            app.processors('BSM').bind(app.BSM_hrtffilenameEditField,'HRTFpath');            
            app.processors('BSM').bind(app.BSM_HeadrotationCheckBox,'headRotation','r',app.BSM_azimuth_disable);
            app.processors('BSM').bind(app.BSM_azimuthKnob,'rot_idx','r',app.update_azimuth); 
            app.BSM_azimuthKnob.Enable = false;
            devices = {};
            info = audiodevinfo;
            for k=1:length(info.output)
                devices(k)={string(info.output(k).Name)}; %#ok<AGROW> 
            end
            app.AudiodeviceDropDown.Items = string(devices);
            
            % read default file
            [app.x,app.fs] = audioread(strcat(app.path,app.file));
            app.sounds(app.file) = signal_holder(app.file,app.x,app.fs);    
            app.origin(app.file)  = signal_holder(app.file,app.x,app.fs);
            app.origin(app.file)  = app.origin(app.file).set_position(app.Source);
            app.origin(app.file) = app.origin(app.file).set_color([0 1 0]);
            app.ProcessPanel.Enable = 'on';
            app.buttonsPanel.Enable = 'on';
            app.filename.Text = strcat(app.path,app.file);
            app.SoundsListBox.Items = {app.file};
            app.SoundsListBox.Value = app.file;
            app.updateSoundView() 
            app.RenameButton.Enable = true;
            app.DeleteButton.Enable = true;
            
            app.SourceListBox.Items = keys(app.origin);
            app.SourceListBox.Value =app.SourceListBox.Items{end};
            updateRoom(app);
        end

        % Button pushed function: processBtn
        function processBtnPushed(app, event)
            
            % run the choosen algorithm and creatin a new signal holder.
            app.buttonsPanel.Enable = 'off';
            app.LoadingSpinner.Visible = 'on';
            drawnow;
            app.x = app.origin;
            for k=1:length(app.selected.Items)
                proccess(app,app.selected.Items{k})
            end
            sound_name = strjoin(app.selected.Items)+string(app.counter);
            app.sounds(sound_name) = signal_holder(sound_name,app.x,app.fs);

            app.SoundsListBox.Items = [app.SoundsListBox.Items cellstr(sound_name)];
            app.counter = app.counter + 1; 
            app.buttonsPanel.Enable = 'on';
            app.LoadingSpinner.Visible = 'off';
       
            app.SoundsListBox.Value =app.SoundsListBox.Items{end};
           
            app.updateSoundView()
       
        end

        % Button pushed function: play
        function playPushed(app, event)
          % play the selected sound      
          s = app.SoundsListBox.Value;
          app.sounds(s).play(app.device);

        end

        % Button pushed function: filePicker
        function filePickerButtonPushed(app, event)
            % add new signal
            [app.file,app.path] = uigetfile('*.wav');
            if app.file
                if ismac
                    app.file(strfind(app.file,'\'))='/';
                    app.path(strfind(app.path,'\'))='/';
                end
            [app.x,app.fs] = audioread(strcat(app.path,app.file));
            app.sounds(app.file) = signal_holder(app.file,app.x,app.fs);
            app.origin(app.file) = signal_holder(app.file,app.x,app.fs);
            
            app.updateSoundView() 
            app.ProcessPanel.Enable = 'on';
            app.buttonsPanel.Enable = 'on';
            app.filename.Text = strcat(app.path,app.file);
            app.SoundsListBox.Items = [app.SoundsListBox.Items cellstr(app.file)];
            app.SourceListBox.Items = [app.SourceListBox.Items cellstr(app.file)];
            app.updateRoom()
            end
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            % show tabs for the selected algorithms
            value = app.ListBox.Value;
            app.selected.Items = value;
            for k=keys(app.tabs)
                if any(strcmp(value,k{1}))
                    tab =  app.tabs(k{1});
                    
                    tab.Parent = app.TabGroup;
                    app.tabs(k{1}) =tab;
                    
                else
                     tab =  app.tabs(k{1});
                    tab.Parent = [];
                    app.tabs(k{1}) =tab;
                end
                
            end
           
        end

        % Callback function
        function roomX_2ValueChanged(app, event)
            % change room size over X axis
            value = app.roomX_2.Value;
            app.room.RoomSize(1)=value;
            app.XSlider_2.Limits = [0,value];
            app.XSlider.Limits = [0,value];
            updateRoom(app)
        end

        % Callback function
        function roomY_2ValueChanged(app, event)
            % change room size over Y axis
            value = app.roomY_2.Value;
            app.room.RoomSize(2)=value;
            app.YSlider_2.Limits = [0,value];
            app.YSlider.Limits = [0,value];
            updateRoom(app)
        end

        % Callback function
        function roomZ_2ValueChanged(app, event)
            % change room size over Z axis
            value = app.roomZ_2.Value;
            app.room.RoomSize(3)=value;
            app.ZSlider_2.Limits = [0,value];
            app.ZSlider.Limits = [0,value];
            updateRoom(app)
        end

        % Value changed function: plots
        function plotsValueChanged(app, event)
            % Disable/Enable plots in proccess
            value = app.plots.Value;
            if value
                set(groot,'defaultFigureVisible','on')
            else
                set(groot,'defaultFigureVisible','off')

            end
        end

        % Value changed function: XSlider_2
        function XSlider_2ValueChanged(app, event)
            % change Source position in the room
            value = app.XSlider_2.Value;
            signal = app.SourceListBox.Value;
            pos = app.origin(signal).position;
            pos(1) = value;
            app.origin(signal) = app.origin(signal).set_position(pos);
            app.updateRoom()
        end

        % Value changed function: YSlider_2
        function YSlider_2ValueChanged(app, event)
            % change Source position in the room
            value = app.YSlider_2.Value;
            signal = app.SourceListBox.Value;
            pos = app.origin(signal).position;
            pos(2) = value;
            app.origin(signal) = app.origin(signal).set_position(pos);
            app.updateRoom()
        end

        % Value changed function: ZSlider_2
        function ZSlider_2ValueChanged(app, event)
            % change Source position in the room
            value = app.ZSlider_2.Value;
            signal = app.SourceListBox.Value;
            pos = app.origin(signal).position;
            pos(3) = value;
            app.origin(signal) = app.origin(signal).set_position(pos);
            app.updateRoom()
        end

        % Value changed function: XSlider
        function XSliderValueChanged(app, event)
            % change Array position in the room
            value = app.XSlider.Value;
            app.room.ArrayPosition(1) = value;
            app.updateRoom()
        end

        % Value changed function: YSlider
        function YSliderValueChanged(app, event)
            % change Array position in the room
            value = app.YSlider.Value;
            app.room.ArrayPosition(2) = value;
            app.updateRoom()
        end

        % Value changed function: ZSlider
        function ZSliderValueChanged(app, event)
            % change Array position in the room
            value = app.ZSlider.Value;
            app.room.ArrayPosition(3) = value;
            app.updateRoom()
        end

        % Selection changed function: SoundViewButtonGroup
        function SoundViewButtonGroupSelectionChanged(app, event)
            % change sound view between FFT and Time
            selectedButton = app.SoundViewButtonGroup.SelectedObject;
            app.soundViewopt = selectedButton.Text;
            app.updateSoundView()
        end

        % Callback function
        function reflactionValueChanged(app, event)
            value = app.reflaction.Value;
            app.room.Reflections = value;
        end

        % Value changed function: DSwitch
        function DSwitchValueChanged(app, event)
            % switch room view between 2D and 3D
            value = app.DSwitch.Value;
            if strcmp(value,'2D')
                 view(app.UIAxes,2)
            else
                view(app.UIAxes,3)

            end
        end

        % Button pushed function: HRTFfileButton
        function HRTFfileButtonPushed(app, event)
            % load hrtf file
            [htrffile,htrfPath] = uigetfile('*.mat');
            if ismac
                    htrffile(strfind(htrffile,'\'))='/';
                    htrfPath(strfind(htrfPath,'\'))='/';
            end
            app.hrtffilenameEditField.Value = strcat(htrfPath,htrffile);
            
            % calling the text field ValueChangedFcn manually becuase
            % matlab cant call function like a normal programing language.
            cellfun(app.hrtffilenameEditField.ValueChangedFcn{1},{app.hrtffilenameEditField},{event},app.hrtffilenameEditField.ValueChangedFcn(2))
            
        end

        % Button pushed function: filesave
        function filesaveButtonPushed(app, event)
            % save sound to file
            s = app.SoundsListBox.Value;
            app.sounds(s).save();
            
            
        end

        % Value changed function: AudiodeviceDropDown
        function AudiodeviceDropDownValueChanged(app, event)
            % change audio device
            value = app.AudiodeviceDropDown.Value;
            app.device = audiodevinfo(0,value);
        end

        % Value changed function: roomX
        function roomXValueChanged(app, event)
            
            value = app.roomX.Value;
            if value ==0
                value = 0.1;
            end
            app.room.RoomSize(1)=value;
            app.XSlider_2.Limits = [0,value];
            app.XSlider.Limits = [0,value];
            updateRoom(app)
        end

        % Value changed function: roomY
        function roomYValueChanged(app, event)
            value = app.roomY.Value;
            if value ==0
                value = 0.1;
            end
            app.room.RoomSize(2)=value;
            app.YSlider_2.Limits = [0,value];
            app.YSlider.Limits = [0,value];
            updateRoom(app)
            
        end

        % Value changed function: roomZ
        function roomZValueChanged(app, event)
            value = app.roomZ.Value;
            if value ==0
                value = 0.1;
            end
            app.room.RoomSize(3)=value;
            
            app.ZSlider_2.Limits = [0,value];
            app.ZSlider.Limits = [0,value];
            updateRoom(app)
            
        end

        % Value changed function: SoundsListBox
        function SoundsListBoxValueChanged(app, event)
            value = app.SoundsListBox.Value;
            app.updateSoundView()
            if strcmp(value,'origin')
                app.RenameButton.Enable = false;
                app.DeleteButton.Enable = false;
            else
                app.RenameButton.Enable = true;
                app.DeleteButton.Enable = true;
            end
            
        end

        % Button pushed function: RenameButton
        function RenameButtonPushed(app, event)
            % rename sound
            s = app.SoundsListBox.Value;
            sound = app.sounds(s);
            new_name = inputdlg("rename "+sound.name + " to: ");
            if ~isempty(new_name)
                if isKey(app.origin,s)
                    or = app.origin(s);
                    or = or.rename(new_name);
                    remove(app.origin,s);
                    app.origin(new_name{1}) = or;
                    app.SourceListBox.Items = keys(app.origin);
                    app.SourceListBox.Value =new_name{1};
                end
                sound = sound.rename(new_name);
                remove(app.sounds,s)
                app.sounds(new_name{1}) = sound;
                app.SoundsListBox.Items = app.sounds.keys();
                app.SoundsListBox.Value = new_name;
            end
            
        end

        % Value changed function: AzimuthKnob
        function AzimuthKnobValueChanged(app, event)
            
            app.updateRoom()
            app.BSM_azimuthKnob.Value = app.AzimuthKnob.Value;
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            % delete sound from list
            s = app.SoundsListBox.Value;
            if ~isempty(app.SoundsListBox.Items) && length(app.SoundsListBox.Items)>=1 
                remove(app.sounds,s);
                if isKey(app.origin,s)
                    remove(app.origin,s);
                    app.SourceListBox.Items = keys(app.origin);
                    app.SourceListBox.Value =app.SourceListBox.Items{end};
                end
                app.SoundsListBox.Items = app.sounds.keys();
                app.SoundsListBox.Value = app.SoundsListBox.Items{1};
                
                
                app.updateSoundView()
                app.updateRoom()
            end
        end

        % Value changed function: REditField
        function REditFieldValueChanged(app, event)
            % update R value and x,y,z accordingly
            [X,y,z] = app.sphere_to_cart(str2double(app.REditField.Value),str2double(app.phiEditField.Value),str2double(app.thetaEditField.Value));
            pos = app.origin(app.SourceListBox.Value).position;
            app.room.ArrayPosition = [pos(1)-X,pos(2)-y,pos(3)-z];
            app.updateRoom()
        end

        % Value changed function: thetaEditField
        function thetaEditFieldValueChanged(app, event)
           % update Theta value and x,y,z accordingly
            [X,y,z] = app.sphere_to_cart(str2double(app.REditField.Value),str2double(app.phiEditField.Value),str2double(app.thetaEditField.Value));
           pos = app.origin(app.SourceListBox.Value).position; 
           app.room.ArrayPosition = [pos(1)-X,pos(2)-y,pos(3)-z];
            app.updateRoom()
        end

        % Value changed function: phiEditField
        function phiEditFieldValueChanged(app, event)
            % update Phi value and x,y,z accordingly
            [X,y,z] = app.sphere_to_cart(str2double(app.REditField.Value),str2double(app.phiEditField.Value),str2double(app.thetaEditField.Value));
             pos = app.origin(app.SourceListBox.Value).position; 
            app.room.ArrayPosition = [pos(1)-X,pos(2)-y,pos(3)-z];
            app.updateRoom()
        end

        % Button pushed function: CaluculateT60Button
        function CaluculateT60ButtonPushed(app, event)
            % calulate T60 and show the results
            [hnm, ~] = image_method.calc_rir(app.fs/2, app.room.RoomSize, app.origin(app.SourceListBox.Value).position, app.room.ArrayPosition, app.room.Reflections, {}, {"array_type", "anm", "N", app.binauralN_arraySpinner.Value});
            T60 = RoomParams.T60(hnm(:,1), app.fs);
            app.T60EditField.Value = sprintf('%.2f',T60);
            
        end

        % Value changed function: BSM_hrtffilenameEditField
        function BSM_hrtffilenameEditFieldValueChanged(app, event)
           
            
        end

        % Button pushed function: BSM_HRTFfileButton
        function BSM_HRTFfileButtonPushed(app, event)
            [htrffile,htrfPath] = uigetfile('*.mat');
            if ismac
                    htrffile(strfind(htrffile,'\'))='/';
                    htrfPath(strfind(htrfPath,'\'))='/';
            end
            app.BSM_hrtffilenameEditField.Value = strcat(htrfPath,htrffile);
            cellfun(app.BSM_hrtffilenameEditField.ValueChangedFcn{1},{app.BSM_hrtffilenameEditField},{event},app.BSM_hrtffilenameEditField.ValueChangedFcn(2))
        end

        % Button pushed function: BSM_T60Button
        function BSM_T60ButtonPushed(app, event)
            [hnm, ~] = image_method.calc_rir(app.fs/2, app.room.RoomSize, app.origin(app.SourceListBox.Value).position, app.room.ArrayPosition, app.room.Reflections, {}, {"array_type", "anm", "N", app.binauralN_arraySpinner.Value});
            T60 = RoomParams.T60(hnm(:,1), app.fs);
            app.BSM_T60EditField.Value = sprintf('%.2f',T60);
        end

        % Value changed function: BSM_azimuthKnob
        function BSM_azimuthKnobValueChanged(app, event)

            app.updateRoom();
            app.AzimuthKnob.Value = app.BSM_azimuthKnob.Value;
        end

        % Value changed function: RoomReflactionSpinner
        function RoomReflactionSpinnerValueChanged(app, event)
            value = app.RoomReflactionSpinner.Value;
            
            app.room.Reflections = value;
        end

        % Value changed function: HeadrotationCheckBox
        function HeadrotationCheckBoxValueChanged(app, event)
          
        end

        % Value changed function: BSM_HeadrotationCheckBox
        function BSM_HeadrotationCheckBoxValueChanged(app, event)
            
        end

        % Value changed function: SourceListBox
        function SourceListBoxValueChanged(app, event)
            % show the selected source location
            value = app.SourceListBox.Value;
            pos = app.origin(value).position;
            app.XSlider_2.Value = pos(1);
            app.YSlider_2.Value = pos(2);
            app.ZSlider_2.Value = pos(3);
            app.updateRoom()
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
             
            delete(app)
            set(groot,'defaultFigureVisible','on')
        end

        % Button pushed function: saveAll
        function saveAllButtonPushed(app, event)
            % save all generated sounds into folder
            all_sound = keys(app.sounds);
            origins = keys(app.origin);
            filtered = all_sound;
            filtered( ismember(all_sound, origins) ) = [];
            selpath = uigetdir();
            for k=1:length(filtered)
                s = filtered{k};
                app.sounds(s).write(selpath);
            end
        end

        % Button pushed function: Bilateral_HRTFfileButton
        function Bilateral_HRTFfileButtonPushed(app, event)
            [htrffile,htrfPath] = uigetfile('*.mat');
            if ismac
                    htrffile(strfind(htrffile,'\'))='/';
                    htrfPath(strfind(htrfPath,'\'))='/';
            end
            app.BilateralhrtffilenameEditField.Value = strcat(htrfPath,htrffile);
            cellfun(app.BilateralhrtffilenameEditField.ValueChangedFcn{1},{app.BilateralhrtffilenameEditField},{event},app.BilateralhrtffilenameEditField.ValueChangedFcn(2))
        end

        % Button pushed function: SaveRoomButton
        function SaveRoomButtonPushed(app, event)
            % save room parameters in json file
            [savefile,savepath] = uiputfile('*.json');
            if savefile
                app.room.Save(strcat(savepath,savefile));
            end
        end

        % Button pushed function: LoadRoomButton
        function LoadRoomButtonPushed(app, event)
            % load room from file
            [savefile,savepath]= uigetfile('*.json');
            if app.file
                app.room = app.room.Load(strcat(strcat(savepath,savefile)));
            end
            app.updateRoom()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Room')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 20];
            app.UIAxes.YLim = [0 20];
            app.UIAxes.ZLim = [0 20];
            app.soundView = uiaxes(app.UIFigure);
            title(app.soundView, 'Signal')
            xlabel(app.soundView, 'X')
            ylabel(app.soundView, 'Y')
            app.plots = uicheckbox(app.UIFigure);
            app.plots.ValueChangedFcn = createCallbackFcn(app, @plotsValueChanged, true);
            app.plots.Text = 'Enable plots';
            app.SoundViewButtonGroup = uibuttongroup(app.UIFigure);
            app.SoundViewButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SoundViewButtonGroupSelectionChanged, true);
            app.SoundViewButtonGroup.Title = 'Sound View';
            app.TimeButton = uiradiobutton(app.SoundViewButtonGroup);
            app.TimeButton.Text = 'Time';
            app.TimeButton.Value = true;
            app.FrequencyButton = uiradiobutton(app.SoundViewButtonGroup);
            app.FrequencyButton.Text = 'Frequency';
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.DSwitch = uiswitch(app.UIFigure, 'toggle');
            app.DSwitch.Items = {'3D', '2D'};
            app.DSwitch.ValueChangedFcn = createCallbackFcn(app, @DSwitchValueChanged, true);
            app.DSwitch.Tooltip = {'Switch from 2D view to 3D'};
            app.DSwitch.Value = '3D';
            app.AudiodeviceDropDownLabel = uilabel(app.UIFigure);
            app.AudiodeviceDropDownLabel.Text = 'Audio device';
            app.AudiodeviceDropDown = uidropdown(app.UIFigure);
            app.AudiodeviceDropDown.Items = {};
            app.AudiodeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @AudiodeviceDropDownValueChanged, true);
            app.AudiodeviceDropDown.Value = {};
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.SelectsourceTab = uitab(app.TabGroup2);
            app.SelectsourceTab.Title = '1-Select source';
            app.filePicker = uibutton(app.SelectsourceTab, 'push');
            app.filePicker.ButtonPushedFcn = createCallbackFcn(app, @filePickerButtonPushed, true);
            app.filePicker.Icon = 'icons8-folder-30.png';
            app.filePicker.Tooltip = {'select a .wav file as input signal'};
            app.filePicker.Text = 'Add File';
            app.filename = uilabel(app.SelectsourceTab);
            app.filename.Text = '';
            app.SelectroomparametersTab = uitab(app.TabGroup2);
            app.SelectroomparametersTab.Title = '2 -Select room parameters';
            app.RoomDimensionsPanel = uipanel(app.SelectroomparametersTab);
            app.RoomDimensionsPanel.Title = 'Room Dimensions';
            app.XLabel = uilabel(app.RoomDimensionsPanel);
            app.XLabel.Text = 'X';
            app.roomX = uislider(app.RoomDimensionsPanel);
            app.roomX.Limits = [0 20];
            app.roomX.ValueChangedFcn = createCallbackFcn(app, @roomXValueChanged, true);
            app.ZSlider_3Label = uilabel(app.RoomDimensionsPanel);
            app.ZSlider_3Label.Text = {'Z'; ''};
            app.roomZ = uislider(app.RoomDimensionsPanel);
            app.roomZ.Limits = [0 20];
            app.roomZ.ValueChangedFcn = createCallbackFcn(app, @roomZValueChanged, true);
            app.YSlider_3Label = uilabel(app.RoomDimensionsPanel);
            app.YSlider_3Label.Text = 'Y';
            app.roomY = uislider(app.RoomDimensionsPanel);
            app.roomY.Limits = [0 20];
            app.roomY.ValueChangedFcn = createCallbackFcn(app, @roomYValueChanged, true);
            app.RoomReflactionLabel = uilabel(app.RoomDimensionsPanel);
            app.RoomReflactionLabel.Text = 'Room Reflaction';
            app.RoomReflactionSpinner = uispinner(app.RoomDimensionsPanel);
            app.RoomReflactionSpinner.Step = 0.05;
            app.RoomReflactionSpinner.Limits = [0 0.99];
            app.RoomReflactionSpinner.ValueChangedFcn = createCallbackFcn(app, @RoomReflactionSpinnerValueChanged, true);
            app.SourcelocationPanel = uipanel(app.SelectroomparametersTab);
            app.SourcelocationPanel.Title = 'Source location';
            app.XSlider_2Label = uilabel(app.SourcelocationPanel);
            app.XSlider_2Label.Text = 'X';
            app.XSlider_2 = uislider(app.SourcelocationPanel);
            app.XSlider_2.Limits = [0 10];
            app.XSlider_2.ValueChangedFcn = createCallbackFcn(app, @XSlider_2ValueChanged, true);
            app.XSlider_2.Tooltip = {'Control the source position along the X axis.'};
            app.YSlider_2Label = uilabel(app.SourcelocationPanel);
            app.YSlider_2Label.Text = 'Y';
            app.YSlider_2 = uislider(app.SourcelocationPanel);
            app.YSlider_2.Limits = [0 10];
            app.YSlider_2.ValueChangedFcn = createCallbackFcn(app, @YSlider_2ValueChanged, true);
            app.YSlider_2.Tooltip = {'Control the source position along the Y axis.'};
            app.ZSlider_2Label = uilabel(app.SourcelocationPanel);
            app.ZSlider_2Label.Text = 'Z';
            app.ZSlider_2 = uislider(app.SourcelocationPanel);
            app.ZSlider_2.Limits = [0 10];
            app.ZSlider_2.ValueChangedFcn = createCallbackFcn(app, @ZSlider_2ValueChanged, true);
            app.ZSlider_2.Tooltip = {'Control the source position along the Z axis.'};
            app.ArraylocationPanel = uipanel(app.SelectroomparametersTab);
            app.ArraylocationPanel.Title = 'Array location';
            app.ZSliderLabel = uilabel(app.ArraylocationPanel);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.ArraylocationPanel);
            app.ZSlider.Limits = [0 10];
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @ZSliderValueChanged, true);
            app.ZSlider.Tooltip = {'Control the microphone array position along the Z axis.'};
            app.YSliderLabel = uilabel(app.ArraylocationPanel);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.ArraylocationPanel);
            app.YSlider.Limits = [0 10];
            app.YSlider.ValueChangedFcn = createCallbackFcn(app, @YSliderValueChanged, true);
            app.YSlider.Tooltip = {'Control the microphone array position along the Y axis.'};
            app.XSliderLabel = uilabel(app.ArraylocationPanel);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.ArraylocationPanel);
            app.XSlider.Limits = [0 10];
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanged, true);
            app.XSlider.Tooltip = {'Control the microphone array position along the X axis.'};
            app.REditFieldLabel = uilabel(app.SelectroomparametersTab);
            app.REditFieldLabel.Interpreter = 'latex';
            app.REditFieldLabel.Tooltip = {'Spherical coordinate Relative to the source'};
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.SelectroomparametersTab, 'text');
            app.REditField.ValueChangedFcn = createCallbackFcn(app, @REditFieldValueChanged, true);
            app.REditField.Tooltip = {'Spherical coordinate Relative to the source'};
            app.phiLabel = uilabel(app.SelectroomparametersTab);
            app.phiLabel.Interpreter = 'latex';
            app.phiLabel.Tooltip = {'Spherical coordinate Relative to the source'};
            app.phiLabel.Text = '$\phi$';
            app.phiEditField = uieditfield(app.SelectroomparametersTab, 'text');
            app.phiEditField.ValueChangedFcn = createCallbackFcn(app, @phiEditFieldValueChanged, true);
            app.thetaEditFieldLabel = uilabel(app.SelectroomparametersTab);
            app.thetaEditFieldLabel.Interpreter = 'latex';
            app.thetaEditFieldLabel.Tooltip = {'Spherical coordinate Relative to the source'};
            app.thetaEditFieldLabel.Text = '$\theta$';
            app.thetaEditField = uieditfield(app.SelectroomparametersTab, 'text');
            app.thetaEditField.ValueChangedFcn = createCallbackFcn(app, @thetaEditFieldValueChanged, true);
            app.thetaEditField.Tooltip = {'Spherical coordinate Relative to the source'};
            app.T60EditField = uieditfield(app.SelectroomparametersTab, 'text');
            app.T60EditField.Editable = 'off';
            app.CaluculateT60Button = uibutton(app.SelectroomparametersTab, 'push');
            app.CaluculateT60Button.ButtonPushedFcn = createCallbackFcn(app, @CaluculateT60ButtonPushed, true);
            app.CaluculateT60Button.Text = 'Caluculate T60';
            app.SourceListBoxLabel = uilabel(app.SelectroomparametersTab);
            app.SourceListBoxLabel.Text = 'Source';
            app.SourceListBox = uilistbox(app.SelectroomparametersTab);
            app.SourceListBox.Items = {'item 1', 'Item 2', 'Item 3', 'Item 4'};
            app.SourceListBox.ValueChangedFcn = createCallbackFcn(app, @SourceListBoxValueChanged, true);
            app.SourceListBox.Value = 'item 1';
            app.SaveRoomButton = uibutton(app.SelectroomparametersTab, 'push');
            app.SaveRoomButton.ButtonPushedFcn = createCallbackFcn(app, @SaveRoomButtonPushed, true);
            app.SaveRoomButton.Icon = 'icons8-save-30.png';
            app.SaveRoomButton.Text = 'Save Room';
            app.LoadRoomButton = uibutton(app.SelectroomparametersTab, 'push');
            app.LoadRoomButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRoomButtonPushed, true);
            app.LoadRoomButton.Icon = 'icons8-folder-30.png';
            app.LoadRoomButton.Text = 'Load Room';
            app.SelectalgorithmTab = uitab(app.TabGroup2);
            app.SelectalgorithmTab.Title = '3- Select algorithm';
            app.ProcessPanel = uipanel(app.SelectalgorithmTab);
            app.ProcessPanel.Title = '2 -  Choose processing algorithm';
            app.ListBox = uilistbox(app.ProcessPanel);
            app.ListBox.Items = {'Als', 'beam', 'image beamforming', 'binaural'};
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Tooltip = {'Select an algrothm for signal processing. Use ctrl+click for multiple choice'};
            app.ListBox.Value = {};
            app.Label = uilabel(app.ProcessPanel);
            app.Label.Text = '';
            app.selectedLabel = uilabel(app.ProcessPanel);
            app.selectedLabel.Text = 'selected';
            app.selected = uilistbox(app.ProcessPanel);
            app.selected.Items = {};
            app.selected.Enable = 'off';
            app.selected.Value = {};
            app.TabGroup = uitabgroup(app.ProcessPanel);
            app.TabGroup.Tooltip = {'Control arguments for specific algorithm'};
            app.AlsTab = uitab(app.TabGroup);
            app.AlsTab.Title = 'Als';
            app.EarlyreflactionsSliderLabel = uilabel(app.AlsTab);
            app.EarlyreflactionsSliderLabel.Text = 'Early reflactions';
            app.EarlyreflactionsSlider = uislider(app.AlsTab);
            app.EarlyreflactionsSlider.Limits = [15 25];
            app.EarlyreflactionsSlider.MajorTicks = [15 16 17 18 19 20 21 22 23 24 25];
            app.EarlyreflactionsSlider.MinorTicks = [];
            app.EarlyreflactionsSlider.Value = 20;
            app.beamformingTab = uitab(app.TabGroup);
            app.beamformingTab.Title = 'beamforming';
            app.N_arraySliderLabel = uilabel(app.beamformingTab);
            app.N_arraySliderLabel.Text = 'N_array';
            app.N_arraySlider = uislider(app.beamformingTab);
            app.N_arraySlider.Limits = [1 8];
            app.N_arraySlider.MinorTicks = [];
            app.N_arraySlider.Tooltip = {'SH order of array'};
            app.N_arraySlider.Value = 4;
            app.r_arraySliderLabel = uilabel(app.beamformingTab);
            app.r_arraySliderLabel.Text = 'r_array';
            app.r_arraySlider = uislider(app.beamformingTab);
            app.r_arraySlider.Limits = [0.03 0.1];
            app.r_arraySlider.Tooltip = {'array radius. 0.042 is similar to EM32 array'};
            app.r_arraySlider.Value = 0.042;
            app.N_array_disp = uilabel(app.beamformingTab);
            app.N_array_disp.Text = '';
            app.binauralTab = uitab(app.TabGroup);
            app.binauralTab.Tooltip = {''};
            app.binauralTab.Title = 'binaural';
            app.r_arraySlider_2Label = uilabel(app.binauralTab);
            app.r_arraySlider_2Label.Text = 'r_array';
            app.binauralr_arraySlider = uislider(app.binauralTab);
            app.binauralr_arraySlider.Limits = [0.03 0.1];
            app.binauralr_arraySlider.Tooltip = {'array radius. 0.042 is similar to EM32 array'};
            app.binauralr_arraySlider.Value = 0.042;
            app.binauralN_array_disp = uilabel(app.binauralTab);
            app.binauralN_array_disp.Text = '';
            app.HRTFfileButton = uibutton(app.binauralTab, 'push');
            app.HRTFfileButton.ButtonPushedFcn = createCallbackFcn(app, @HRTFfileButtonPushed, true);
            app.HRTFfileButton.Tooltip = {'Select HRTF file.'};
            app.HRTFfileButton.Text = {'HRTF file'; ''};
            app.hrtffilenameEditField = uieditfield(app.binauralTab, 'text');
            app.hrtffilenameEditField.Editable = 'off';
            app.N_arraySpinnerLabel = uilabel(app.binauralTab);
            app.N_arraySpinnerLabel.Text = {'N_array'; ''};
            app.binauralN_arraySpinner = uispinner(app.binauralTab);
            app.binauralN_arraySpinner.Limits = [1 8];
            app.binauralN_arraySpinner.Tooltip = {'"SH order of array"'};
            app.binauralN_arraySpinner.Value = 4;
            app.SH_orderLabel = uilabel(app.binauralTab);
            app.SH_orderLabel.Text = 'SH_order';
            app.SHorderSpinner = uispinner(app.binauralTab);
            app.SHorderSpinner.Limits = [10 20];
            app.SHorderSpinner.Tooltip = {'SH order of plane-wave synthesis'};
            app.SHorderSpinner.Value = 15;
            app.HeadrotationCheckBox = uicheckbox(app.binauralTab);
            app.HeadrotationCheckBox.ValueChangedFcn = createCallbackFcn(app, @HeadrotationCheckBoxValueChanged, true);
            app.HeadrotationCheckBox.Text = 'Head rotation';
            app.AzimuthKnobLabel = uilabel(app.binauralTab);
            app.AzimuthKnobLabel.Text = 'Azimuth';
            app.AzimuthKnob = uiknob(app.binauralTab, 'continuous');
            app.AzimuthKnob.Limits = [0 360];
            app.AzimuthKnob.ValueChangedFcn = createCallbackFcn(app, @AzimuthKnobValueChanged, true);
            app.imagebeamformingTab = uitab(app.TabGroup);
            app.imagebeamformingTab.Title = 'image beamforming';
            app.N_arraySlider_2Label = uilabel(app.imagebeamformingTab);
            app.N_arraySlider_2Label.Text = 'N_array';
            app.N_image = uislider(app.imagebeamformingTab);
            app.N_image.Limits = [1 8];
            app.N_image.MinorTicks = [];
            app.N_image.Value = 4;
            app.BSMTab = uitab(app.TabGroup);
            app.BSMTab.Title = 'BSM';
            app.BSM_HeadrotationCheckBox = uicheckbox(app.BSMTab);
            app.BSM_HeadrotationCheckBox.ValueChangedFcn = createCallbackFcn(app, @BSM_HeadrotationCheckBoxValueChanged, true);
            app.BSM_HeadrotationCheckBox.Text = 'Head rotation';
            app.BSM_arrayRadiusSlider = uislider(app.BSMTab);
            app.BSM_arrayRadiusSlider.Limits = [0.03 0.2];
            app.BSM_arrayRadiusSlider.Tooltip = {'array radius. 0.042 is similar to EM32 array'};
            app.BSM_arrayRadiusSlider.Value = 0.1;
            app.BSM_ArrayRadiusLabel = uilabel(app.BSMTab);
            app.BSM_ArrayRadiusLabel.Text = 'Array Radius';
            app.BSM_HRTFfileButton = uibutton(app.BSMTab, 'push');
            app.BSM_HRTFfileButton.ButtonPushedFcn = createCallbackFcn(app, @BSM_HRTFfileButtonPushed, true);
            app.BSM_HRTFfileButton.Tooltip = {'Select HRTF file.'};
            app.BSM_HRTFfileButton.Text = {'HRTF file'; ''};
            app.BSM_hrtffilenameEditField = uieditfield(app.BSMTab, 'text');
            app.BSM_hrtffilenameEditField.ValueChangedFcn = createCallbackFcn(app, @BSM_hrtffilenameEditFieldValueChanged, true);
            app.BSM_hrtffilenameEditField.Editable = 'off';
            app.BSM_RigidDropDown = uidropdown(app.BSMTab);
            app.BSM_RigidDropDown.Items = {'Rigid', 'Open'};
            app.BSM_RigidDropDown.Value = 'Rigid';
            app.AzimuthLabel = uilabel(app.BSMTab);
            app.AzimuthLabel.Text = 'Azimuth';
            app.BSM_azimuthKnob = uiknob(app.BSMTab, 'continuous');
            app.BSM_azimuthKnob.Limits = [1 360];
            app.BSM_azimuthKnob.ValueChangedFcn = createCallbackFcn(app, @BSM_azimuthKnobValueChanged, true);
            app.BSM_azimuthKnob.Value = 1;
            app.ArrayTypeDropDownLabel = uilabel(app.BSMTab);
            app.ArrayTypeDropDownLabel.Text = 'Array Type';
            app.BSM_ArrayTypeDropDown = uidropdown(app.BSMTab);
            app.BSM_ArrayTypeDropDown.Items = {'Spherical', 'Semi_Circular', 'Fully_Circule'};
            app.BSM_ArrayTypeDropDown.Value = 'Semi_Circular';
            app.MicsSpinnerLabel = uilabel(app.BSMTab);
            app.MicsSpinnerLabel.Text = 'Mics #';
            app.BSM_MicsSpinner = uispinner(app.BSMTab);
            app.BSM_MicsSpinner.Limits = [1 Inf];
            app.BSM_MicsSpinner.Value = 6;
            app.SHordersoundfieldSpinnerLabel = uilabel(app.BSMTab);
            app.SHordersoundfieldSpinnerLabel.Text = 'SH order (sound field)';
            app.BSM_SHorderSpinner = uispinner(app.BSMTab);
            app.BSM_SHorderSpinner.Limits = [1 Inf];
            app.BSM_SHorderSpinner.Value = 15;
            app.BSM_T60EditField = uieditfield(app.BSMTab, 'text');
            app.BSM_T60EditField.Editable = 'off';
            app.BSM_T60Button = uibutton(app.BSMTab, 'push');
            app.BSM_T60Button.ButtonPushedFcn = createCallbackFcn(app, @BSM_T60ButtonPushed, true);
            app.BSM_T60Button.Text = 'T60';
            app.Bilateral = uitab(app.TabGroup);
            app.Bilateral.Title = 'Bilateral';
            app.N_bilatSpinnerLabel = uilabel(app.Bilateral);
            app.N_bilatSpinnerLabel.Text = 'N_bilat';
            app.N_bilatSpinner = uispinner(app.Bilateral);
            app.HeadRotationCheckBox = uicheckbox(app.Bilateral);
            app.HeadRotationCheckBox.Text = 'HeadRotation';
            app.head_radiusEditFieldLabel = uilabel(app.Bilateral);
            app.head_radiusEditFieldLabel.Text = 'head_radius';
            app.head_radiusEditField = uieditfield(app.Bilateral, 'numeric');
            app.BilateralhrtffilenameEditField = uieditfield(app.Bilateral, 'text');
            app.BilateralhrtffilenameEditField.Editable = 'off';
            app.headrotationdegEditFieldLabel = uilabel(app.Bilateral);
            app.headrotationdegEditFieldLabel.Text = 'head rotation [deg]';
            app.headrotationdegEditField = uieditfield(app.Bilateral, 'numeric');
            app.Band_Limited_TranslationCheckBox = uicheckbox(app.Bilateral);
            app.Band_Limited_TranslationCheckBox.Text = 'Band_Limited_Translation';
            app.CutoffKhzEditFieldLabel = uilabel(app.Bilateral);
            app.CutoffKhzEditFieldLabel.Text = 'Cutoff [Khz]';
            app.CutoffKhzEditField = uieditfield(app.Bilateral, 'numeric');
            app.WidthKhzEditFieldLabel = uilabel(app.Bilateral);
            app.WidthKhzEditFieldLabel.Text = 'Width [Khz]';
            app.WidthKhzEditField = uieditfield(app.Bilateral, 'numeric');
            app.Bilateral_HRTFfileButton = uibutton(app.Bilateral, 'push');
            app.Bilateral_HRTFfileButton.ButtonPushedFcn = createCallbackFcn(app, @Bilateral_HRTFfileButtonPushed, true);
            app.Bilateral_HRTFfileButton.Tooltip = {'Select HRTF file.'};
            app.Bilateral_HRTFfileButton.Text = {'HRTF file'; ''};
            app.playandproccessTab = uitab(app.TabGroup2);
            app.playandproccessTab.Title = '4 - play and proccess';
            app.buttonsPanel = uipanel(app.playandproccessTab);
            app.buttonsPanel.Title = '3 - Run the algoritms.';
            app.play = uibutton(app.buttonsPanel, 'push');
            app.play.ButtonPushedFcn = createCallbackFcn(app, @playPushed, true);
            app.play.Icon = 'icons8-play-24.png';
            app.play.Tooltip = {'play the wav signal'};
            app.play.Text = 'Play';
            app.processBtn = uibutton(app.buttonsPanel, 'push');
            app.processBtn.ButtonPushedFcn = createCallbackFcn(app, @processBtnPushed, true);
            app.processBtn.Icon = 'icons8-process-30.png';
            app.processBtn.Tooltip = {'Run every algoritm in the selected list by order.'};
            app.processBtn.Text = 'Process';
            app.filesave = uibutton(app.buttonsPanel, 'push');
            app.filesave.ButtonPushedFcn = createCallbackFcn(app, @filesaveButtonPushed, true);
            app.filesave.Icon = 'icons8-save-30.png';
            app.filesave.Tooltip = {'Save signal as .wav file'};
            app.filesave.Text = 'Save File';
            app.bypass = uicheckbox(app.buttonsPanel);
            app.bypass.Text = {'original signal'; ''};
            app.saveAll = uibutton(app.buttonsPanel, 'push');
            app.saveAll.ButtonPushedFcn = createCallbackFcn(app, @saveAllButtonPushed, true);
            app.saveAll.Icon = 'icons8-save-all-30.png';
            app.saveAll.Tooltip = {'Save signal as .wav file'};
            app.saveAll.Text = 'Save All';
            app.LoadingSpinner = uihtml(app.playandproccessTab);
            app.LoadingSpinner.HTMLSource = '<head><style>div {  border: 16px solid #f3f3f3; /* Light grey */  border-top: 16px solid #3498db; /* Blue */  border-radius: 50%;  width: 80px;  height: 80px;  animation: spin 2s linear infinite;}@keyframes spin {  0% { transform: rotate(0deg); }  100% { transform: rotate(360deg); }}</style>   </head><div class="loader"></div>   ';
            app.LoadingSpinner.Visible = 'off';
            app.RenameButton = uibutton(app.UIFigure, 'push');
            app.RenameButton.ButtonPushedFcn = createCallbackFcn(app, @RenameButtonPushed, true);
            app.RenameButton.Icon = 'icons8-rename-24.png';
            app.RenameButton.Enable = 'off';
            app.RenameButton.Text = 'Rename';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Icon = 'icons8-delete-30.png';
            app.DeleteButton.Enable = 'off';
            app.DeleteButton.Text = 'Delete';
            app.SoundsListBoxLabel = uilabel(app.UIFigure);
            app.SoundsListBoxLabel.Text = 'Sounds';
            app.SoundsListBox = uilistbox(app.UIFigure);
            app.SoundsListBox.Items = {};
            app.SoundsListBox.ValueChangedFcn = createCallbackFcn(app, @SoundsListBoxValueChanged, true);
            app.SoundsListBox.Value = {};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo.png';
            app.BGUAcousticsLabLabel = uilabel(app.UIFigure);
            app.BGUAcousticsLabLabel.Text = 'BGU Acoustics Lab';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'icons8-audio.gif';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ToolBox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end